classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image3                          matlab.ui.control.Image
        MessagesLabel                   matlab.ui.control.Label
        pamLabel                        matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        PlLabel                         matlab.ui.control.Label
        kgmLabel                        matlab.ui.control.Label
        mhLabel                         matlab.ui.control.Label
        MassevolumiqueLabel             matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Lamp_7                          matlab.ui.control.Lamp
        hauteurEditField                matlab.ui.control.NumericEditField
        hauteurLabel                    matlab.ui.control.Label
        RsistanceEditField              matlab.ui.control.NumericEditField
        RsistanceEditFieldLabel         matlab.ui.control.Label
        PermiabilitdugateauEditField    matlab.ui.control.NumericEditField
        PermiabilitdugateauEditFieldLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        rgimeLabel                      matlab.ui.control.Label
        danslegateauEditField           matlab.ui.control.NumericEditField
        danslegateauEditFieldLabel      matlab.ui.control.Label
        NReynoldsEditField              matlab.ui.control.NumericEditField
        NReynoldsEditFieldLabel         matlab.ui.control.Label
        vitesseenfutvideEditField       matlab.ui.control.NumericEditField
        vitesseenfutvideEditFieldLabel  matlab.ui.control.Label
        calculerButton                  matlab.ui.control.Button
        dbitEditField                   matlab.ui.control.NumericEditField
        dbitLabel                       matlab.ui.control.Label
        diamtredugrainEditField         matlab.ui.control.NumericEditField
        diamtredugrainEditFieldLabel    matlab.ui.control.Label
        PorositdugateauEditField        matlab.ui.control.NumericEditField
        PorositdugateauEditFieldLabel   matlab.ui.control.Label
        viscositduliquideEditField      matlab.ui.control.NumericEditField
        viscositduliquideEditFieldLabel  matlab.ui.control.Label
        MassevolumiqueEditField         matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        MassevolumiqueEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculerButton
        function calculerButtonPushed(app, event)

            MV = app.MassevolumiqueEditField.Value;
            viscosite = app.viscositduliquideEditField.Value;
            porosite = app.PorositdugateauEditField.Value;
            debit = app.dbitEditField.Value;
            diametreD = app.diamtredugrainEditField.Value;
            Z = app.hauteurEditField.Value;

            % calcule de vitesse en fut vide
                vitesse = (debit/3600)/1;

            app.vitesseenfutvideEditField.Value = vitesse;

            % calcule de pouecentage dans le gateau 

                app.danslegateauEditField.Value=(1-porosite)*100;

            % calcule du nombre de reynoldes

            app.NReynoldsEditField.Value=(MV*vitesse)/((6/diametreD)*(1-porosite)*viscosite);

           if app.NReynoldsEditField.Value < 2000.00 && app.NReynoldsEditField.Value >= 0.00
                    app.Label.Text = 'Laminaire';
                    
           elseif app.NReynoldsEditField.Value > 2000.00
                    app.Label.Text = 'Turbulant';
           end

           %calcule de la permiabilité du gateau 

           app.PermiabilitdugateauEditField.Value = (porosite)^3/(4.16*((6/diametreD)^2)*((1-porosite)^2)*viscosite);
                

           %calcule de la résistance du gateau 

           app.RsistanceEditField.Value = (Z*10^-3)/app.PermiabilitdugateauEditField.Value;


            % checking if edit field is empty by using lamps 
           % check if editfield is empty 

           if MV == 0
               app.Lamp.Color = 'red';

           else 
               app.Lamp.Color = 'green';
           end

           % check if editfield is empty 

           if viscosite == 0
               app.Lamp_2.Color = 'red';

           else 
               app.Lamp_2.Color = 'green';
           end
           
           % check if editfield is empty 

           if porosite == 0
               app.Lamp_3.Color = 'red';

           else 
               app.Lamp_3.Color = 'green';
           end

         
           % check if editfield is empty 

           if diametreD == 0
               app.Lamp_4.Color = 'red';

           else 
               app.Lamp_4.Color = 'green';
           end

           % check if editfield is empty 

           if debit == 0
               app.Lamp_5.Color = 'red';

           else 
               app.Lamp_5.Color = 'green';
           end


           % check if editfield is empty 

           if Z == 0
               app.Lamp_7.Color = 'red';

           else 
               app.Lamp_7.Color = 'green';
           end
            
           % Error message 
           if MV == 0 || viscosite == 0 || porosite == 0 || diametreD == 0 || debit == 0 || Z==0

               app.Label_2.Text ='!! SVP fait remplit les champs où la lamp est en rouge !!';
               app.Label_2.BackgroundColor = [0.91,0.57,0.57];
               app.Label_2.FontColor = 'red';

           else 
              app.Label_2.BackgroundColor = 'None';
              app.Label_2.Text = 'Votre calcule est pret';
              app.Label_2.FontColor = 'green';


           end
        end

        % Value changed function: MassevolumiqueEditField
        function MassevolumiqueEditFieldValueChanged(app, event)
            value = app.MassevolumiqueEditField.Value;
            
            switch value 
                case 0
                    app.Lamp.Color = [0.90,0.90,0.90];
                otherwise
                    app.Lamp.Color = 'green';
            end
        end

        % Value changed function: viscositduliquideEditField
        function viscositduliquideEditFieldValueChanged(app, event)
            value = app.viscositduliquideEditField.Value;
            switch value 
                case 0
                    app.Lamp_2.Color = [0.90,0.90,0.90];
                otherwise
                    app.Lamp_2.Color = 'green';
            end
        end

        % Value changed function: PorositdugateauEditField
        function PorositdugateauEditFieldValueChanged(app, event)
            value = app.PorositdugateauEditField.Value;
            switch value 
                case 0
                    app.Lamp_3.Color = [0.90,0.90,0.90];
                otherwise
                    app.Lamp_3.Color = 'green';
            end
        end

        % Value changed function: diamtredugrainEditField
        function diamtredugrainEditFieldValueChanged(app, event)
            value = app.diamtredugrainEditField.Value;
            switch value 
                case 0
                    app.Lamp_4.Color = [0.90,0.90,0.90];
                otherwise
                    app.Lamp_4.Color = 'green';
            end
        end

        % Value changed function: dbitEditField
        function dbitEditFieldValueChanged(app, event)
            value = app.dbitEditField.Value;
            switch value 
                case 0
                    app.Lamp_5.Color = [0.90,0.90,0.90];
                otherwise
                    app.Lamp_5.Color = 'green';
            end
        end

        % Value changed function: hauteurEditField
        function hauteurEditFieldValueChanged(app, event)
            value = app.hauteurEditField.Value;
            switch value 
                case 0
                    app.Lamp_7.Color = [0.90,0.90,0.90];
                otherwise
                    app.Lamp_7.Color = 'green';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MassevolumiqueEditFieldLabel = uilabel(app.UIFigure);
            app.MassevolumiqueEditFieldLabel.Text = 'Masse volumique';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'blue-and-green-triangles.jpg');
            app.MassevolumiqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassevolumiqueEditField.Limits = [0 Inf];
            app.MassevolumiqueEditField.ValueChangedFcn = createCallbackFcn(app, @MassevolumiqueEditFieldValueChanged, true);
            app.viscositduliquideEditFieldLabel = uilabel(app.UIFigure);
            app.viscositduliquideEditFieldLabel.Text = 'viscosité du liquide';
            app.viscositduliquideEditField = uieditfield(app.UIFigure, 'numeric');
            app.viscositduliquideEditField.Limits = [0 Inf];
            app.viscositduliquideEditField.ValueChangedFcn = createCallbackFcn(app, @viscositduliquideEditFieldValueChanged, true);
            app.PorositdugateauEditFieldLabel = uilabel(app.UIFigure);
            app.PorositdugateauEditFieldLabel.Text = 'Porosité du gateau';
            app.PorositdugateauEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorositdugateauEditField.Limits = [0 Inf];
            app.PorositdugateauEditField.ValueChangedFcn = createCallbackFcn(app, @PorositdugateauEditFieldValueChanged, true);
            app.diamtredugrainEditFieldLabel = uilabel(app.UIFigure);
            app.diamtredugrainEditFieldLabel.Text = 'diamétre du grain';
            app.diamtredugrainEditField = uieditfield(app.UIFigure, 'numeric');
            app.diamtredugrainEditField.Limits = [0 Inf];
            app.diamtredugrainEditField.ValueChangedFcn = createCallbackFcn(app, @diamtredugrainEditFieldValueChanged, true);
            app.dbitLabel = uilabel(app.UIFigure);
            app.dbitLabel.Text = 'débit                   ';
            app.dbitEditField = uieditfield(app.UIFigure, 'numeric');
            app.dbitEditField.Limits = [0 Inf];
            app.dbitEditField.ValueChangedFcn = createCallbackFcn(app, @dbitEditFieldValueChanged, true);
            app.calculerButton = uibutton(app.UIFigure, 'push');
            app.calculerButton.ButtonPushedFcn = createCallbackFcn(app, @calculerButtonPushed, true);
            app.calculerButton.Text = 'calculer';
            app.vitesseenfutvideEditFieldLabel = uilabel(app.UIFigure);
            app.vitesseenfutvideEditFieldLabel.Text = 'vitesse en fut vide';
            app.vitesseenfutvideEditField = uieditfield(app.UIFigure, 'numeric');
            app.NReynoldsEditFieldLabel = uilabel(app.UIFigure);
            app.NReynoldsEditFieldLabel.Text = 'N Reynolds';
            app.NReynoldsEditField = uieditfield(app.UIFigure, 'numeric');
            app.danslegateauEditFieldLabel = uilabel(app.UIFigure);
            app.danslegateauEditFieldLabel.Text = '% dans le gateau';
            app.danslegateauEditField = uieditfield(app.UIFigure, 'numeric');
            app.rgimeLabel = uilabel(app.UIFigure);
            app.rgimeLabel.Text = 'régime :';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.PermiabilitdugateauEditFieldLabel = uilabel(app.UIFigure);
            app.PermiabilitdugateauEditFieldLabel.Text = 'Permiabilité du gateau';
            app.PermiabilitdugateauEditField = uieditfield(app.UIFigure, 'numeric');
            app.RsistanceEditFieldLabel = uilabel(app.UIFigure);
            app.RsistanceEditFieldLabel.Text = 'Résistance';
            app.RsistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.hauteurLabel = uilabel(app.UIFigure);
            app.hauteurLabel.Text = 'hauteur                ';
            app.hauteurEditField = uieditfield(app.UIFigure, 'numeric');
            app.hauteurEditField.ValueChangedFcn = createCallbackFcn(app, @hauteurEditFieldValueChanged, true);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.MassevolumiqueLabel = uilabel(app.UIFigure);
            app.MassevolumiqueLabel.Text = 'Masse volumique';
            app.mhLabel = uilabel(app.UIFigure);
            app.mhLabel.Text = '(m³/h)';
            app.kgmLabel = uilabel(app.UIFigure);
            app.kgmLabel.Text = '(kg/m³)';
            app.PlLabel = uilabel(app.UIFigure);
            app.PlLabel.Text = '(Pl)';
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = '(mm)';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '(m)';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = '(m²)';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = '(m/s)';
            app.pamLabel = uilabel(app.UIFigure);
            app.pamLabel.Text = '(pa/m³)';
            app.MessagesLabel = uilabel(app.UIFigure);
            app.MessagesLabel.Text = 'Messages :';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'final_600070ad3a1339.31702566.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end