classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label12                     matlab.ui.control.Label
        Label11                     matlab.ui.control.Label
        Label10                     matlab.ui.control.Label
        Label9                      matlab.ui.control.Label
        Label8                      matlab.ui.control.Label
        Label7                      matlab.ui.control.Label
        Label6                      matlab.ui.control.Label
        Label5                      matlab.ui.control.Label
        Label4                      matlab.ui.control.Label
        Label3                      matlab.ui.control.Label
        Label2                      matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        ZoudPEditField              matlab.ui.control.NumericEditField
        ZoudPEditFieldLabel         matlab.ui.control.Label
        CalculerletempspourButton   matlab.ui.control.Button
        EditField_2                 matlab.ui.control.EditField
        viderletableauButton        matlab.ui.control.Button
        dPEditField                 matlab.ui.control.NumericEditField
        dPEditFieldLabel            matlab.ui.control.Label
        TracerlacourbededPftButton  matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        VoirlavaleurdeRButton       matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        VoirlquationdeghraphButton  matlab.ui.control.Button
        VoirlesdonnesdutableaudanscefichierButton  matlab.ui.control.Button
        TracerlacourbedezftButton   matlab.ui.control.Button
        ajouterlesdonnesButton      matlab.ui.control.Button
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        TempsEditField              matlab.ui.control.NumericEditField
        TempsEditFieldLabel         matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ajouterlesdonnesButton
        function ajouterlesdonnesButtonPushed(app, event)
            Temps = app.TempsEditField.Value;
            Z = app.ZEditField.Value;
            dP = app.dPEditField.Value;

            newData = {Temps, Z, dP};
            currentData = get(app.UITable, 'Data');
            newTableData = [currentData; newData];
            set(app.UITable, 'Data', newTableData);

            app.TempsEditField.Value = 0;
            app.ZEditField.Value = 0;
            app.dPEditField.Value = 0;
      

        end

        % Button pushed function: TracerlacourbedezftButton
        function TracerlacourbedezftButtonPushed(app, event)

        x = table2array(app.UITable.Data(:,"Temps"));
        y = table2array(app.UITable.Data(:,"Z"));
        plot(app.UIAxes,x,y);
        xlabel(app.UIAxes,'Temps (min)');
        ylabel(app.UIAxes,'Z (mm)');
        title(app.UIAxes,'Z=f(t)');

        app.Label.Text = ' ';
        app.Label_2.Text = ' ';
        end

        % Button pushed function: VoirlesdonnesdutableaudanscefichierButton
        function VoirlesdonnesdutableaudanscefichierButtonPushed(app, event)
         t = readtable(app.EditField_2.Value,"sheet",1, 'Range', 'A:C');
            app.UITable.Data = t;
        end

        % Button pushed function: VoirlquationdeghraphButton
        function VoirlquationdeghraphButtonPushed(app, event)
            % Retrieve the x and y data of the plot
    x = app.UIAxes.Children.XData;
    y = app.UIAxes.Children.YData;


    % Generate the equation of the plot
    p = polyfit(x, y, 1);
    eqn = sprintf('y = %.2fx + %.2f', p(1), p(2));

    % Update the text of the label with the equation
    app.Label.Text = eqn;
        end

        % Button pushed function: VoirlavaleurdeRButton
        function VoirlavaleurdeRButtonPushed(app, event)
            % Retrieve the x and y data of the plot
    x = app.UIAxes.Children.XData;
    y = app.UIAxes.Children.YData;

    % Generate the R-squared value
    p = polyfit(x, y, 1);
    yfit = polyval(p,x);
    yresid = y - yfit;
    SSresid = sum(yresid.^2);
    SStotal = (length(y)-1) * var(y);
    rsq = 1 - SSresid/SStotal;
    eqn = sprintf('R² = %.2f', rsq);

    % Update the text of the label with the equation and R-squared value
    app.Label_2.Text = eqn;
        end

        % Button pushed function: TracerlacourbededPftButton
        function TracerlacourbededPftButtonPushed(app, event)
        
        x = table2array(app.UITable.Data(:,"Temps"));
        y = table2array(app.UITable.Data(:,"dP"));
        plot(app.UIAxes,x,y);
        xlabel(app.UIAxes,'Temps (min)');
        ylabel(app.UIAxes,'dP (mbar)');
        title(app.UIAxes,'dP=f(t)');
        app.Label.Text = ' ';
        app.Label_2.Text = ' ';
        end

        % Button pushed function: viderletableauButton
        function viderletableauButtonPushed(app, event)
            app.UITable.Data = {};
            app.EditField_2.Value = '';
        end

        % Button pushed function: CalculerletempspourButton
        function CalculerletempspourButtonPushed(app, event)
        % Get x and y data from UIAxes
        x = app.UIAxes.Children.XData;
        y = app.UIAxes.Children.YData;

        % Fit a linear polynomial to the data
        p = polyfit(x, y, 1);

        % Equation to solve for x
        eqn = sprintf('y = %.2fx + %.2f', p(1), p(2));

        % Add an edit field and label to the app UI to input and display the value of y

        % Add a button and modify its callback to execute the following code
        y = app.ZoudPEditField.Value;
        x = (y - p(2)) / p(1);
        app.Label_4.Text = sprintf('t = %.2f', x);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Temps'; 'Z'; 'dP'};
            app.UITable.RowName = {};
            app.TempsEditFieldLabel = uilabel(app.UIFigure);
            app.TempsEditFieldLabel.Text = 'Temps';
            app.TempsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ajouterlesdonnesButton = uibutton(app.UIFigure, 'push');
            app.ajouterlesdonnesButton.ButtonPushedFcn = createCallbackFcn(app, @ajouterlesdonnesButtonPushed, true);
            app.ajouterlesdonnesButton.Text = 'ajouter les données';
            app.TracerlacourbedezftButton = uibutton(app.UIFigure, 'push');
            app.TracerlacourbedezftButton.ButtonPushedFcn = createCallbackFcn(app, @TracerlacourbedezftButtonPushed, true);
            app.TracerlacourbedezftButton.Text = {'Tracer la courbe'; ' de z=f(t)'};
            app.VoirlesdonnesdutableaudanscefichierButton = uibutton(app.UIFigure, 'push');
            app.VoirlesdonnesdutableaudanscefichierButton.ButtonPushedFcn = createCallbackFcn(app, @VoirlesdonnesdutableaudanscefichierButtonPushed, true);
            app.VoirlesdonnesdutableaudanscefichierButton.Text = 'Voir les données du tableau dans ce fichier';
            app.VoirlquationdeghraphButton = uibutton(app.UIFigure, 'push');
            app.VoirlquationdeghraphButton.ButtonPushedFcn = createCallbackFcn(app, @VoirlquationdeghraphButtonPushed, true);
            app.VoirlquationdeghraphButton.Text = 'Voir l''équation de ghraph';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.VoirlavaleurdeRButton = uibutton(app.UIFigure, 'push');
            app.VoirlavaleurdeRButton.ButtonPushedFcn = createCallbackFcn(app, @VoirlavaleurdeRButtonPushed, true);
            app.VoirlavaleurdeRButton.Text = 'Voir la valeur de R²';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TracerlacourbededPftButton = uibutton(app.UIFigure, 'push');
            app.TracerlacourbededPftButton.ButtonPushedFcn = createCallbackFcn(app, @TracerlacourbededPftButtonPushed, true);
            app.TracerlacourbededPftButton.Text = {'Tracer la courbe'; ' de dP=f(t)'};
            app.dPEditFieldLabel = uilabel(app.UIFigure);
            app.dPEditFieldLabel.Text = 'dP';
            app.dPEditField = uieditfield(app.UIFigure, 'numeric');
            app.viderletableauButton = uibutton(app.UIFigure, 'push');
            app.viderletableauButton.ButtonPushedFcn = createCallbackFcn(app, @viderletableauButtonPushed, true);
            app.viderletableauButton.Text = 'vider le tableau';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.CalculerletempspourButton = uibutton(app.UIFigure, 'push');
            app.CalculerletempspourButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerletempspourButtonPushed, true);
            app.CalculerletempspourButton.Text = 'Calculer le temps pour ';
            app.ZoudPEditFieldLabel = uilabel(app.UIFigure);
            app.ZoudPEditFieldLabel.Text = 'Z ou dP =';
            app.ZoudPEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Label6';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Label7';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'Label8';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Label9';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Label10';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'Label11';
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = 'Label12';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end